classdef DASTfixation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Symbol    matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainapp % Description
    end
    
    methods (Access = public)
        
        function changeSymbol(app,state)
            
            if state == "listen"
                app.Symbol.Text = app.mainapp.data.Settings.ListenSymbol;
                app.Symbol.FontColor = app.mainapp.data.Settings.ListenSymbolColor;
            elseif state == "repeat"
                app.Symbol.Text = app.mainapp.data.Settings.RepeatSymbol;
                app.Symbol.FontColor = app.mainapp.data.Settings.RepeatSymbolColor;
            end
            drawnow;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            mp = get(groot,'MonitorPositions');

            app.mainapp = mainapp;

            app.UIFigure.Color = app.mainapp.data.Settings.ScreenBackground;
            app.Symbol.Text = app.mainapp.data.Settings.ListenSymbol;
            app.Symbol.FontColor = app.mainapp.data.Settings.ListenSymbolColor;

            app.UIFigure.Position = mp(str2double(app.mainapp.data.Settings.ScreenNumber),:);
            
            app.UIFigure.WindowState = 'fullscreen';
            drawnow;


        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            
            app.Symbol.Position(3:4) = app.UIFigure.Position(3:4);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.Symbol = uilabel(app.UIFigure);
            app.Symbol.Text = '+';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DASTfixation(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end