classdef DASTgaze < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NogazedatadetectedLabel         matlab.ui.control.Label
        PlotPanel                       matlab.ui.container.Panel
        TrialSpinner                    matlab.ui.control.Spinner
        TrialSpinnerLabel               matlab.ui.control.Label
        ValidCheckBoxR                  matlab.ui.control.CheckBox
        ValidCheckBoxL                  matlab.ui.control.CheckBox
        UIAxesRight                     matlab.ui.control.UIAxes
        UIAxesRight_2                   matlab.ui.control.UIAxes
        UIAxesLeft_2                    matlab.ui.control.UIAxes
        UIAxesLeft                      matlab.ui.control.UIAxes
        PreprocessingSettingsPanel      matlab.ui.container.Panel
        BaselinewindowsEditField        matlab.ui.control.EditField
        BaselinewindowsLabel            matlab.ui.control.Label
        ResponseWindowsEditField        matlab.ui.control.EditField
        ResponseWindowsEditFieldLabel   matlab.ui.control.Label
        MaxmissingforvalidityEditField  matlab.ui.control.EditField
        MaxmissingLabel                 matlab.ui.control.Label
        LowPassWindowsEditField         matlab.ui.control.EditField
        LowPassWindowsEditFieldLabel    matlab.ui.control.Label
        MingaplengthforextensionmsEditField  matlab.ui.control.EditField
        MingaplengthforextensionmsEditFieldLabel  matlab.ui.control.Label
        GapExtension                    matlab.ui.control.EditField
        GapExtensionLabel               matlab.ui.control.Label
        DifferenceThresholdEditField    matlab.ui.control.EditField
        DifferenceThresholdEditFieldLabel  matlab.ui.control.Label
        RangeEditField                  matlab.ui.control.EditField
        RangeEditFieldLabel             matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.Button
        UploadedDataEditField           matlab.ui.control.EditField
        LoadPresetButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        plotData    % Description
        Param       %settings parameters
        check       %used to check if variables have been passed from th emain app
        var
    end
    
    methods (Access = private)
        
        function CheckFieldNames(app)
            
            s = app.plotData.inputData.GazeStruct(1).SubGazeStruct;

            fields = fieldnames(s);
            left_single_fields = {};
            
            for i = 1:numel(fields)
                if startsWith(fields{i}, 'left') && isa(s.(fields{i}), 'single')
                    left_single_fields{end+1} = fields{i}; %#ok<AGROW>
                end
            end
            
            disp(left_single_fields);
        end
        
        function plotPupilData(app)
        
                currTrial = app.TrialSpinner.Value;
                
                app.ValidCheckBoxL.Value = app.plotData.Validity(currTrial,1);
                app.ValidCheckBoxR.Value = app.plotData.Validity(currTrial,2);

                % Left
                fieldNames = {'UIAxesLeft';'UIAxesRight'};
                for lr = 1:2
                    cla(app.(fieldNames{lr}))
                    hold(app.(fieldNames{lr}), "on");
                    plot(app.(fieldNames{lr}), app.plotData.responseTime, app.plotData.Raw_Diameter{lr}(:,currTrial));
                    plot(app.(fieldNames{lr}), app.plotData.responseTime, app.plotData.Cleaned_Diameter{lr}(:,currTrial));
                    plot(app.(fieldNames{lr}), app.plotData.responseTime, app.plotData.Repaired_Diameter{lr}(:,currTrial), LineWidth=2,Color=[0 0 0]);
                    legend(app.(fieldNames{lr}),'Raw', 'Cleaned', 'Low Pass');
                end
        end


        function checkvalidity(app)
                       
            Val_R = mean(isnan(app.plotData.Cleaned_Diameter_R_B(:,app.Param.ResponseWindow(1):app.Param.ResponseWindow(2))),2);
            Val_L = mean(isnan(app.plotData.Cleaned_Diameter_L_B(:,app.Param.ResponseWindow(1):app.Param.ResponseWindow(2))),2);
            
            %check validity of the data
            app.plotData.Val_R_logic = Val_R < app.Param.DelThresh;
            app.plotData.Val_L_logic = Val_L < app.Param.DelThresh;

        end

        function updatePlot(app)
            
            cla(app.UIAxesLeft);
            cla(app.UIAxesRight);

            % plot
            plotPupilData(app);
    
            fieldNames = {'UIAxesLeft_2';'UIAxesRight_2'};
            Color = [0 0 1; 1 0 0];
            for lr = 1:2
                objsToDelete = findall(app.(fieldNames{lr}), 'Type', 'line'); % linee
                patchesToDelete = findall(app.(fieldNames{lr}), 'Type', 'patch'); % fill / patch
                delete([objsToDelete; patchesToDelete]);
                plotmeansem(app.plotData.responseTime,app.plotData.Baselined_Diameter{lr}(:,find(app.plotData.Validity(:,lr))),Color(lr,:),app.(fieldNames{lr}))
            end

        end
        
        function updatePupilData(app)
            
            app.PlotPanel.Enable = 'off';

            % Cleaning
            app.Param.Range = str2double(split(erase(app.RangeEditField.Value, ["[", "]"]))).'; % e.g. [mm] or quantile: Set values outside this range to NaN
            app.Param.RangeQuantile = false;
            app.Param.DiffThresh = str2double(app.DifferenceThresholdEditField.Value); % [mm] or [Pixels]: If the difference from one sample to the next sample exceeds this thereshold, both samples will be set to NaN.
            app.Param.DiffQuantile = false;

            % Preprocessing
            app.Param.Fs = app.plotData.inputData.Settings.FsEyetracker; % [Hz]: Sampling rate of pupil data
            app.Param.RemoveBeforeAndAfter = str2double(split(erase(app.GapExtension.Value, ["[", "]"]))).'*1e-3; % [s]: Samples within the time ramge before and after NaNs will set NaNs as well
            app.Param.MinLengthNanRepair = ceil(str2double(app.MingaplengthforextensionmsEditField.Value)*app.Param.Fs/1000); % [samples]: Drop values (i.e., change to NaN) before and after NaNs only for contiguous NaNs of at least __ samples.
            app.Param.LPWinSize = str2double(app.LowPassWindowsEditField.Value); % [s]: Window size of hamming-window for low-pass filtering

            % Time windows
            app.Param.BaselineWindow = str2double(split(erase(app.BaselinewindowsEditField.Value, ["[", "]"])));
            app.Param.ResponseWindow = str2double(split(erase(app.ResponseWindowsEditField.Value, ["[", "]"])));
            app.Param.DurationSilence = app.plotData.inputData.Settings.DurationSilenceBefore;
            app.Param.DurationNoise = app.plotData.inputData.Settings.DurationBefore;
            app.Param.BaselineSamples = round((app.Param.DurationSilence + app.Param.DurationNoise + app.Param.BaselineWindow)*app.Param.Fs) + 1;
            app.Param.ResponseSamples = round((app.Param.DurationSilence + app.Param.DurationNoise + app.Param.ResponseWindow)*app.Param.Fs) + 1;
            
            % Validity
            app.Param.DelThresh = str2double(app.MaxmissingforvalidityEditField.Value)/100;
                       
            % Prepare low-pass window
            app.plotData.LPWindow = hamming(round(app.Param.LPWinSize*app.Param.Fs));
            app.plotData.LPWindow = app.plotData.LPWindow/sum(app.plotData.LPWindow);

            % Collect pupil data
            app.plotData.Ntrials = numel(app.plotData.inputData.EyeData.Gaze); 
            fieldNames = {'left_pupil_diameter';'right_pupil_diameter'};         
            baseline = app.Param.BaselineSamples(1):app.Param.BaselineSamples(2);
            response = app.Param.ResponseSamples(1):app.Param.ResponseSamples(2);
            app.plotData.Raw_Diameter = cell(1,2);
            app.plotData.Cleaned_Diameter = cell(1,2);
            app.plotData.Repaired_Diameter = cell(1,2);
            app.plotData.Baselined_Diameter = cell(1,2);
            app.plotData.Validity = nan(app.plotData.Ntrials,2);

            % Run through Trials
            for tt = 1:app.plotData.Ntrials
                for lr = 1:2
                    rawData = app.plotData.inputData.EyeData.Gaze{tt}.(fieldNames{lr});
                    app.plotData.Raw_Diameter{lr}(:,tt) = rawData(response);
                    cleanedData = setnan(rawData, app.Param);
                    app.plotData.Cleaned_Diameter{lr}(:,tt) = cleanedData(response);
                    app.plotData.Validity(tt,lr) = mean(isnan(cleanedData(response))) < app.Param.DelThresh;
                    repairedData = padfilter(preprocpupil(cleanedData,app.Param),app.plotData.LPWindow);
                    app.plotData.Repaired_Diameter{lr}(:,tt) = repairedData(response);
                    app.plotData.Baselined_Diameter{lr}(:,tt) = repairedData(response) - mean(repairedData(baseline));
                end
            end

            app.plotData.responseTime = app.Param.ResponseWindow(1):(1/app.Param.Fs):app.Param.ResponseWindow(2); 

            app.TrialSpinner.Limits = [1 app.plotData.Ntrials];
            app.PlotPanel.Enable = 'on';
            app.PlotPanel.Visible = 'on';

            app.updatePlot;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, path)
            app.check.path = false;

            if exist("path", "var")
                app.UploadedDataEditField.Value = path;  
                app.check.path = true;
            end

        end

        % Button pushed function: LoadPresetButton
        function LoadPresetButtonPushed(app, event)
            
            dirname = append(pwd, '\results');
            [filename,pathname] = uigetfile(fullfile(dirname,'*.mat'));
            if filename==0
                % user pressed cancel
                drawnow;
                figure(app.UIFigure);
                return
            else

                app.UploadedDataEditField.Value = filename;
                figure(app.UIFigure);

                app.var.filename = filename;
                app.var.pathname = pathname;
               
                drawnow;

            end

            % Get focus back to the main figure
            focus(app.UIFigure);

        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
           
                % loading variable
                fullfilename = fullfile(app.var.pathname,app.var.filename);
                load(fullfilename,'data');
                app.plotData.inputData = data;

            if isfield(app.plotData.inputData, 'EyeData')
                app.NogazedatadetectedLabel.Visible = 'off';
                app.PreprocessingSettingsPanel.Visible = "on";
                app.updatePupilData;
            else 
                app.NogazedatadetectedLabel.Visible = 'on';
            end


        end

        % Value changed function: ValidCheckBoxR
        function ValidCheckBoxRValueChanged(app, event)
            value = app.ValidCheckBoxR.Value;
            num = app.TrialSpinner.Value;
            app.plotData.Validity(num,2) = value;
            updatePlot(app)
        end

        % Value changed function: ValidCheckBoxL
        function ValidCheckBoxLValueChanged(app, event)
            value = app.ValidCheckBoxL.Value;
            num = app.TrialSpinner.Value;
            app.plotData.Validity(num,1) = value;
            updatePlot(app)
        end

        % Value changed function: TrialSpinner
        function TrialSpinnerValueChanged(app, event)
            
            num = app.TrialSpinner.Value;
            app.ValidCheckBoxL.Value = app.plotData.Validity(num,1);
            app.ValidCheckBoxR.Value = app.plotData.Validity(num,2);

            updatePlot(app)

        end

        % Value changed function: BaselinewindowsEditField, 
        % ...and 7 other components
        function RangeEditFieldValueChanged(app, event)
            
            app.updatePupilData;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadPresetButton = uibutton(app.UIFigure, 'push');
            app.LoadPresetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPresetButtonPushed, true);
            app.LoadPresetButton.Text = 'Select File';
            app.UploadedDataEditField = uieditfield(app.UIFigure, 'text');
            app.UploadedDataEditField.Editable = 'off';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.PreprocessingSettingsPanel = uipanel(app.UIFigure);
            app.PreprocessingSettingsPanel.Title = 'Preprocessing Settings';
            app.PreprocessingSettingsPanel.Visible = 'off';
            app.RangeEditFieldLabel = uilabel(app.PreprocessingSettingsPanel);
            app.RangeEditFieldLabel.Text = 'Range ';
            app.RangeEditField = uieditfield(app.PreprocessingSettingsPanel, 'text');
            app.RangeEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEditFieldValueChanged, true);
            app.RangeEditField.Tooltip = {' [mm] Set values outside this range to NaN'};
            app.RangeEditField.Value = '[1 8]';
            app.DifferenceThresholdEditFieldLabel = uilabel(app.PreprocessingSettingsPanel);
            app.DifferenceThresholdEditFieldLabel.Text = 'Difference Threshold';
            app.DifferenceThresholdEditField = uieditfield(app.PreprocessingSettingsPanel, 'text');
            app.DifferenceThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEditFieldValueChanged, true);
            app.DifferenceThresholdEditField.Tooltip = {' [mm] or quantile: If the difference from one sample to the next sample exceeds this thereshold, both samples will be set to NaN.'};
            app.DifferenceThresholdEditField.Value = '1';
            app.GapExtensionLabel = uilabel(app.PreprocessingSettingsPanel);
            app.GapExtensionLabel.Text = 'Gap Extension [ms]';
            app.GapExtension = uieditfield(app.PreprocessingSettingsPanel, 'text');
            app.GapExtension.ValueChangedFcn = createCallbackFcn(app, @RangeEditFieldValueChanged, true);
            app.GapExtension.Tooltip = {'Samples within the time ramge before and after NaNs will set NaNs as well'};
            app.GapExtension.Value = '[35 100]';
            app.MingaplengthforextensionmsEditFieldLabel = uilabel(app.PreprocessingSettingsPanel);
            app.MingaplengthforextensionmsEditFieldLabel.Text = 'Min. gap length for extension [ms]';
            app.MingaplengthforextensionmsEditField = uieditfield(app.PreprocessingSettingsPanel, 'text');
            app.MingaplengthforextensionmsEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEditFieldValueChanged, true);
            app.MingaplengthforextensionmsEditField.Tooltip = {'Drop values (i.e., change to NaN) before and after NaNs only for contiguous NaNs of at least __ samples.'};
            app.MingaplengthforextensionmsEditField.Value = '10';
            app.LowPassWindowsEditFieldLabel = uilabel(app.PreprocessingSettingsPanel);
            app.LowPassWindowsEditFieldLabel.Text = 'Low Pass  Window [s]';
            app.LowPassWindowsEditField = uieditfield(app.PreprocessingSettingsPanel, 'text');
            app.LowPassWindowsEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEditFieldValueChanged, true);
            app.LowPassWindowsEditField.Tooltip = {' Window size of hamming-window for low-pass filtering'};
            app.LowPassWindowsEditField.Value = '.5';
            app.MaxmissingLabel = uilabel(app.PreprocessingSettingsPanel);
            app.MaxmissingLabel.Text = 'Max. % missing for validity';
            app.MaxmissingforvalidityEditField = uieditfield(app.PreprocessingSettingsPanel, 'text');
            app.MaxmissingforvalidityEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEditFieldValueChanged, true);
            app.MaxmissingforvalidityEditField.Tooltip = {'Samples within the time ramge before and after NaNs will set NaNs as well'};
            app.MaxmissingforvalidityEditField.Value = '30';
            app.ResponseWindowsEditFieldLabel = uilabel(app.PreprocessingSettingsPanel);
            app.ResponseWindowsEditFieldLabel.Text = 'Response Window [s]';
            app.ResponseWindowsEditField = uieditfield(app.PreprocessingSettingsPanel, 'text');
            app.ResponseWindowsEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEditFieldValueChanged, true);
            app.ResponseWindowsEditField.Tooltip = {'Time window in seconds counted after the sentence onset'};
            app.ResponseWindowsEditField.Value = '[-1 4]';
            app.BaselinewindowsLabel = uilabel(app.PreprocessingSettingsPanel);
            app.BaselinewindowsLabel.Text = 'Baseline window [s]';
            app.BaselinewindowsEditField = uieditfield(app.PreprocessingSettingsPanel, 'text');
            app.BaselinewindowsEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEditFieldValueChanged, true);
            app.BaselinewindowsEditField.Tooltip = {'Time window in seconds counted after the sentence onset'};
            app.BaselinewindowsEditField.Value = '[-1 0]';
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Visible = 'off';
            app.UIAxesLeft = uiaxes(app.PlotPanel);
            title(app.UIAxesLeft, 'Left Eye')
            xlabel(app.UIAxesLeft, 'Time [s]')
            ylabel(app.UIAxesLeft, 'Relative Pupil Diameter [mm]')
            zlabel(app.UIAxesLeft, 'Z')
            app.UIAxesLeft_2 = uiaxes(app.PlotPanel);
            title(app.UIAxesLeft_2, 'Left Eye Grand Average ')
            xlabel(app.UIAxesLeft_2, 'Time [s]')
            ylabel(app.UIAxesLeft_2, 'Relative Pupil Diameter [mm]')
            zlabel(app.UIAxesLeft_2, 'Z')
            app.UIAxesRight_2 = uiaxes(app.PlotPanel);
            title(app.UIAxesRight_2, 'Right Eye Grand Average ')
            xlabel(app.UIAxesRight_2, 'Time [s]')
            ylabel(app.UIAxesRight_2, 'Relative Pupil Diameter [mm]')
            zlabel(app.UIAxesRight_2, 'Z')
            app.UIAxesRight = uiaxes(app.PlotPanel);
            title(app.UIAxesRight, 'RightEye')
            xlabel(app.UIAxesRight, 'Time [s]')
            ylabel(app.UIAxesRight, 'Relative Pupil Diameter [mm]')
            zlabel(app.UIAxesRight, 'Z')
            app.ValidCheckBoxL = uicheckbox(app.PlotPanel);
            app.ValidCheckBoxL.ValueChangedFcn = createCallbackFcn(app, @ValidCheckBoxLValueChanged, true);
            app.ValidCheckBoxL.Text = 'Valid';
            app.ValidCheckBoxR = uicheckbox(app.PlotPanel);
            app.ValidCheckBoxR.ValueChangedFcn = createCallbackFcn(app, @ValidCheckBoxRValueChanged, true);
            app.ValidCheckBoxR.Text = 'Valid';
            app.TrialSpinnerLabel = uilabel(app.PlotPanel);
            app.TrialSpinnerLabel.Text = 'Trial';
            app.TrialSpinner = uispinner(app.PlotPanel);
            app.TrialSpinner.ValueChangedFcn = createCallbackFcn(app, @TrialSpinnerValueChanged, true);
            app.NogazedatadetectedLabel = uilabel(app.UIFigure);
            app.NogazedatadetectedLabel.Visible = 'off';
            app.NogazedatadetectedLabel.Text = 'No gaze data detected.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DASTgaze(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end